classdef prac1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        RecordVoiceTab               matlab.ui.container.Tab
        Label_2                      matlab.ui.control.Label
        Entertext                    matlab.ui.control.EditField
        EntertexthereEditFieldLabel  matlab.ui.control.Label
        lamp                         matlab.ui.control.Lamp
        Label                        matlab.ui.control.Label
        StartRecordingButton         matlab.ui.control.StateButton
        PlotedRecordingTab           matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        PlotIsolatedSignalTab        matlab.ui.container.Tab
        speakers1                    matlab.ui.container.ButtonGroup
        Speaker3Button_4             matlab.ui.control.RadioButton
        Speaker2Button_4             matlab.ui.control.RadioButton
        Speaker1Button_4             matlab.ui.control.RadioButton
        ISOLATERECORDINGButton       matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        PlaybackTab                  matlab.ui.container.Tab
        ClearButton                  matlab.ui.control.Button
        PlayButton                   matlab.ui.control.Button
        listbox                      matlab.ui.control.ListBox
        WordstochoosefromListBox_3Label  matlab.ui.control.Label
        ChooseWordEdit               matlab.ui.control.EditField
        WhatwouldyouliketoplaybackfromthelistwordsentenceEditFieldLabel  matlab.ui.control.Label
        speakers                     matlab.ui.container.ButtonGroup
        Speaker3Button_3             matlab.ui.control.RadioButton
        Speaker2Button_3             matlab.ui.control.RadioButton
        Speaker1Button_3             matlab.ui.control.RadioButton
        UIAxes3                      matlab.ui.control.UIAxes
        ConversationTab              matlab.ui.container.Tab
        FullDialogue                 matlab.ui.control.TextArea
        FullDialogueTextAreaLabel    matlab.ui.control.Label
        InputDialogue                matlab.ui.control.EditField
        Inputdialogue1sentenceatatimeEditFieldLabel  matlab.ui.control.Label
        Play2                        matlab.ui.control.Button
        ADD                          matlab.ui.control.Button
        Clear                        matlab.ui.control.Button
        listbox2                     matlab.ui.control.ListBox
        WordstochoosefromListBox_2Label  matlab.ui.control.Label
        speakers3                    matlab.ui.container.ButtonGroup
        Speaker3Button_5             matlab.ui.control.RadioButton
        Speaker2Button_5             matlab.ui.control.RadioButton
        Speaker1Button_5             matlab.ui.control.RadioButton
        ExitProgramTab               matlab.ui.container.Tab
        ExitButton                   matlab.ui.control.Button
    end

      
    properties (Access = private)
        objRec = audiorecorder(8000, 24, 1)
        recordedAudioData % store recorded audio data
        words = [];     
        recordingCount = 0;
       end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StartRecordingButton
        function StartRecordingButtonValueChanged(app, event)
            value = app.StartRecordingButton.Value;
           
            % Start recording
            if value
                app.lamp.Color = 'red'; % Turn lamp red to indicate recording
                app.StartRecordingButton.Text = "Recording";
                record(app.objRec);
            else
                app.StartRecordingButton.Text = "Start Recording";
                stop(app.objRec); % Stop recording
                app.recordedAudioData = getaudiodata(app.objRec); % Store the recorded audio data
                app.lamp.Color = 'green'; % Turn lamp green to indicate recording stopped
                
                app.recordingCount = app.recordingCount + 1;
        
                filename = fullfile(pwd, sprintf('recordedAudio_%d.wav', app.recordingCount)); %filename for recording 
        
                audiowrite(filename, app.recordedAudioData, app.objRec.SampleRate); %save full recording
                
                inputString = app.Entertext.Value;
                
                app.words = strsplit(inputString, ' '); % Split string with spaces

                %plot
                plot(app.UIAxes, app.recordedAudioData);               
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            stop(app.objRec);
            delete(app);
        end

        % Button pushed function: ISOLATERECORDINGButton
        function ISOLATERECORDINGButtonPushed(app, event)
         if isempty(app.recordedAudioData)
             uialert(app.UIFigure, 'No recorded audio data found.', 'Error');
             return;
         end
                  
         Fs = app.objRec.SampleRate; % Sampling frequency
         audioData = app.recordedAudioData;
         
         %boundaries
         threshold = 0.004; 
         maxGapLength = 0.3;  % to not split a word with a pause into 2
         
         %Segments above and below threshold into "straight" line
         audioEnergy = abs(audioData);
         aboveThreshold = audioEnergy > threshold;
         belowThreshold = audioData < -threshold;
        
         combinedDetection = aboveThreshold | belowThreshold; % to make square wave
         
         % Find start and end points of isolated segments by checking 0/1
         isolatedStartPoints = find(diff(combinedDetection) == 1);
         isolatedEndPoints = find(diff(combinedDetection) == -1); 
          
         % as woorde afsny
         if length(isolatedStartPoints) > length(isolatedEndPoints)
             isolatedEndPoints = [isolatedEndPoints; length(audioData)];
         elseif length(isolatedStartPoints) < length(isolatedEndPoints)
             isolatedStartPoints = [1; isolatedStartPoints];
         end
                  
         combinedSegments = [];
         currentStart = isolatedStartPoints(1);
         currentEnd = isolatedEndPoints(1);
         
         for i = 2:length(isolatedStartPoints)
             gapLength = (isolatedStartPoints(i) - currentEnd) / Fs; % Length between 2 words. 
             
             if gapLength <= maxGapLength        % If statement=  for determineing if a word has has a pause between explosive sounds  
                 
                 currentEnd = isolatedEndPoints(i);
             else
                 
                 combinedSegments = [combinedSegments; max(currentStart, 1), min(currentEnd, length(audioData))]; %#ok<AGROW> ,   combinedSegments = die isolated words
                 
                 currentStart = isolatedStartPoints(i);
                 currentEnd = isolatedEndPoints(i);
             end
         end
                
        combinedSegments = [combinedSegments; currentStart, currentEnd]; %al die saved words se posisie
         
        numSegments = size(combinedSegments, 1); %hoe baie woorde daar is
        
        %error pop up
        if numel(app.words) < numSegments
            uialert(app.UIFigure, 'Number of words is less than the number of segments in the recording.', 'Error');
            return;
        elseif numel(app.words) > numSegments
            uialert(app.UIFigure, 'Number of words is more than the number of segments in the recording.', 'Error');
            return;
        end

         isolatedData = zeros(size(audioData));
         
         %haal noise uit 
         for i = 1:size(combinedSegments, 1)
             currentStart = combinedSegments(i, 1);  
             currentEnd = combinedSegments(i, 2);
                          
             isolatedData(currentStart:currentEnd) = audioData(currentStart:currentEnd);
         end
         
         
         cla(app.UIAxes2); 
         plot(app.UIAxes2, isolatedData);
         
         %info pop up
         numSegments = size(combinedSegments, 1);
         msg = sprintf('Combined %d segments.', numSegments);
         uialert(app.UIFigure, msg, 'Info',"Icon","info");
         
         if numel(app.words) ~= numSegments
             uialert(app.UIFigure, 'Number of words does not match the number of words in recording', 'Error');
             return;
         end
                     
            % directories 
            if app.Speaker1Button_4.Value
                speakerDir = 'speaker1';
            elseif app.Speaker2Button_4.Value
                speakerDir = 'speaker2';
            elseif app.Speaker3Button_4.Value
                speakerDir = 'speaker3';
            else
                uialert(app.UIFigure, 'No speaker selected.', 'Error');
                return;
            end
            
           
            %error pop up
            if ~exist(speakerDir, 'dir')
                [status, msg] = mkdir(speakerDir);
                if ~status
                    uialert(app.UIFigure, sprintf('Failed to create speaker directory: %s\nError: %s', speakerDir, msg), 'Error');
                    return;
                end
            end
            
            %error pop up
            if size(combinedSegments, 1) ~= numel(app.words)
                uialert(app.UIFigure, 'Number of words does not match the number of segments.', 'Error');
                return;
            end
            
            % Save each word as a file in the speaker's directory
            for i = 1:numel(app.words)
                filename = fullfile('C:','Users','roedi','Desktop','Semester 2','EERI 222','Practical 1',speakerDir, app.words{i} + ".wav");
            
               
                if i > size(combinedSegments, 1)
                    uialert(app.UIFigure, sprintf('Index %d exceeds number of segments.', i), 'Error');
                    return;
                end
            
                currentStart = combinedSegments(i, 1);
                currentEnd = combinedSegments(i, 2);
            
                % error pop up
                if currentStart < 1 || currentEnd > length(isolatedData)
                    uialert(app.UIFigure, sprintf('Segment indices out of bounds for file: %s', filename), 'Error');
                    return;
                end
 
                % Write the audio file
                try
                    audiowrite(filename, isolatedData(currentStart:currentEnd), Fs);
                    
                catch ME
                    uialert(app.UIFigure, sprintf('Failed to write file: %s\nError: %s', filename, ME.message), 'Error');
                    return;
                end
            end
            
            
            uialert(app.UIFigure, 'Audio files saved successfully.', 'Success',"Icon","success");


        end

        % Value changed function: listbox
        function listboxValueChanged(app, event)
   
    selectedWord = app.listbox.Value;
    
    % error pop up
    if isempty(selectedWord)
        uialert(app.UIFigure, 'No word selected. Please select an audio file.', 'Error');
        return;
    end
    
    % error pop up
    if ~isfile(selectedWord)
        uialert(app.UIFigure, sprintf('The file "%s" does not exist.', selectedWord), 'Error');
        return;
    end
    
    try
        % Read the audio file
        [y, Fs] = audioread(selectedWord);
              
    catch ME
        % error pop up
        uialert(app.UIFigure, sprintf('Failed to read file: %s\nError: %s', selectedWord, ME.message), 'Error');
    end

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
  
    selectedWords = strsplit(app.ChooseWordEdit.Value, ' ');

   
    if app.Speaker1Button_3.Value
        speakerPrefix = 'speaker1';
    elseif app.Speaker2Button_3.Value
        speakerPrefix = 'speaker2';
    else
        speakerPrefix = 'speaker3';
    end
   
    audioDirectory = fullfile('C:', 'Users', 'roedi', 'Desktop', 'Semester 2', 'EERI 222', 'Practical 1');

    
    for i = 1:numel(selectedWords)
        
        filename = fullfile(audioDirectory, speakerPrefix, selectedWords{i} + ".wav");

        % error pop up
        if ~isfile(filename)
            uialert(app.UIFigure, sprintf('The file "%s" does not exist.', selectedWords{i}), 'Error');
            continue; 
        end

        try
            % Read the audio file
            [y, Fs] = audioread(filename);

            
            cla(app.UIAxes3); 
            plot(app.UIAxes3, ((1:length(y))/Fs), y);                     
            xlabel(app.UIAxes3, 'Time (s)');
            ylabel(app.UIAxes3, 'Amplitude');
            title(app.UIAxes3, sprintf('Playback Waveform: %s', selectedWords{i}));
           
            player = audioplayer(y, Fs);

            playblocking(player);
            
            if i < numel(selectedWords) % Pause only if there are more words to play
                pause(0.3); % Pause for 0.3s after the sound
            end

        catch ME
            % error pop up
            uialert(app.UIFigure, sprintf('Failed to play file: %s\nError: %s', filename, ME.message), 'Error');
        end
    end

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
        speakersSelectionChanged(app);
        
        app.TabGroup.SelectionChangedFcn = @(src, event) TabGroupSelectionChanged(app, event); 
        end

        % Selection changed function: speakers
        function speakersSelectionChanged(app, event)
    audioDirectory = fullfile('C:', 'Users', 'roedi', 'Desktop', 'Semester 2', 'EERI 222', 'Practical 1');

    if app.Speaker1Button_3.Value
        speakerPrefix = 'speaker1';
    elseif app.Speaker2Button_3.Value
        speakerPrefix = 'speaker2';
    else
        speakerPrefix = 'speaker3';
    end
    
    fileList = dir(fullfile(audioDirectory, speakerPrefix, '*.wav'));
    
    availableWords = {};

    for i = 1:numel(fileList)
        fileName = fileList(i).name;
        [~, word, ~] = fileparts(fileName);  % without extension
        availableWords{end+1} = word;  
    end

    app.listbox.Items = availableWords; 


        end

        % Selection changed function: speakers3
        function speakers3SelectionChanged(app, event)
        audioDirectory = fullfile('C:', 'Users', 'roedi', 'Desktop', 'Semester 2', 'EERI 222', 'Practical 1');
    
        if app.Speaker1Button_5.Value
            speakerPrefix = 'speaker1';
        elseif app.Speaker2Button_5.Value
            speakerPrefix = 'speaker2';
        else
            speakerPrefix = 'speaker3';
        end
    
        fileList = dir(fullfile(audioDirectory, speakerPrefix, '*.wav'));
          
        availableWords = {};
    
        for i = 1:numel(fileList)
            fileName = fileList(i).name;
            [~, word, ~] = fileparts(fileName);  % without extension
            availableWords{end+1} = word;  
        end
    
        app.listbox2.Items = availableWords; 
            
        end

        % Value changed function: listbox2
        function listbox2ValueChanged(app, event)
           selectedWord = app.listbox2.Value;
            
            %error pop up
            if isempty(selectedWord)
                uialert(app.UIFigure, 'No word selected. Please select an audio file.', 'Error');
                return;
            end
            
            % error pop up
            if ~isfile(selectedWord)
                uialert(app.UIFigure, sprintf('The file "%s" does not exist.', selectedWord), 'Error');
                return;
            end
            
            try
                % Read the audio file
                [y, Fs] = audioread(selectedWord);
            
            catch ME
                % error pop up
                uialert(app.UIFigure, sprintf('Failed to read file: %s\nError: %s', selectedWord{i}, ME.message), 'Error');
            end

        end

        % Button pushed function: ADD
        function ADDPushed(app, event)
    sentence = char(app.InputDialogue.Value); % Convert cell to char if needed

    % error pop up
    if isempty(sentence)
        uialert(app.UIFigure, 'Input dialogue is empty.', 'Error');
        return;
    end
  
    if app.Speaker1Button_5.Value
        speakerPrefix = 'speaker1';
    elseif app.Speaker2Button_5.Value
        speakerPrefix = 'speaker2';
    elseif app.Speaker3Button_5.Value
        speakerPrefix = 'speaker3';
    else
        uialert(app.UIFigure, 'No speaker selected.', 'Error');
        return;
    end

    audioDirectory = fullfile('C:', 'Users', 'roedi', 'Desktop', 'Semester 2', 'EERI 222', 'Practical 1');

    words = strsplit(sentence);

    for i = 1:numel(words)
      
        filename = fullfile(audioDirectory, speakerPrefix, [words{i} '.wav']);
        
        % error pop up
        if ~isfile(filename)
            uialert(app.UIFigure, sprintf('The file "%s" does not exist for word "%s".', filename, words{i}), 'Error');
            return; 
        end
    end
   
    newEntry = sprintf('%s: %s', speakerPrefix, sentence);
  
    if iscell(app.FullDialogue.Value)
        currentDialogue = strjoin(app.FullDialogue.Value, '\n');
    elseif ischar(app.FullDialogue.Value)
        currentDialogue = app.FullDialogue.Value;
    elseif isstring(app.FullDialogue.Value)
        currentDialogue = char(app.FullDialogue.Value);
    else
        uialert(app.UIFigure, 'FullDialogue is not in a valid format.', 'Error');
        return;
    end
  
    if isempty(currentDialogue)
        app.FullDialogue.Value = newEntry;
    else
        app.FullDialogue.Value = sprintf('%s\n%s', currentDialogue, newEntry);
    end
    
    app.InputDialogue.Value = '';

    
        end

        % Button pushed function: Play2
        function Play2Pushed(app, event)
    if iscell(app.FullDialogue.Value)
      
        fullDialogueText = strjoin(app.FullDialogue.Value, '\n');
    elseif ischar(app.FullDialogue.Value)
        
        fullDialogueText = string(app.FullDialogue.Value);
    elseif isstring(app.FullDialogue.Value)
        fullDialogueText = app.FullDialogue.Value;
    else
        uialert(app.UIFigure, 'FullDialogue is not in a valid format.', 'Error');
        return;
    end

    sentences = strsplit(fullDialogueText, '\n');
    
    audioDirectory = fullfile('C:', 'Users', 'roedi', 'Desktop', 'Semester 2', 'EERI 222', 'Practical 1');
          
    for s = 1:numel(sentences)
        parts = strsplit(sentences{s}, ':');
        
        if numel(parts) < 2
            continue; 
        end
     
        speakerPrefix = strtrim(parts{1});
        sentence = strtrim(parts{2});
   
        switch speakerPrefix
            case 'speaker1'
                speakerDir = 'speaker1';
            case 'speaker2'
                speakerDir = 'speaker2';
            case 'speaker3'
                speakerDir = 'speaker3';
            otherwise
                uialert(app.UIFigure, sprintf('Unknown speaker: %s', speakerPrefix), 'Error');
                continue;
        end
        
       words = strsplit(sentence, ' ');
     
        for w = 1:numel(words)
        
            filename = fullfile(audioDirectory, speakerDir, [words{w}, '.wav']);
            
            %error pop up
            if ~isfile(filename)
                uialert(app.UIFigure, sprintf('The file "%s" does not exist.', filename), 'Error');
                continue; 
            end
            
            try
                % Read the audio file
                [y, Fs] = audioread(filename);
                                              
                player = audioplayer(y, Fs);
             
                playblocking(player);
                   
                if w < numel(words)
                    pause(0.3);
                end
                
            catch ME
                % error pop up
                uialert(app.UIFigure, sprintf('Failed to play file: %s\nError: %s', filename, ME.message), 'Error');
            end
        end
            
        pause(1.0);     % Pause between sentences
    end
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
      app.FullDialogue.Value = '';
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed2(app, event)
    app.ChooseWordEdit.Value = '';
        end

        % Button down function: ConversationTab
        function ConversationTabButtonDown(app, event)
        audioDirectory = fullfile('C:', 'Users', 'roedi', 'Desktop', 'Semester 2', 'EERI 222', 'Practical 1');
    
        if app.Speaker1Button_5.Value
            speakerPrefix = 'speaker1';
        elseif app.Speaker2Button_5.Value
            speakerPrefix = 'speaker2';
        else
            speakerPrefix = 'speaker3';
        end
    
        fileList = dir(fullfile(audioDirectory, speakerPrefix, '*.wav'));
    
        availableWords = {};
   
        for i = 1:numel(fileList)
            fileName = fileList(i).name;
            [~, word, ~] = fileparts(fileName);  % without extension
            availableWords{end+1} = word;  
        end
    
        app.listbox2.Items = availableWords; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.RecordVoiceTab = uitab(app.TabGroup);
            app.RecordVoiceTab.Title = 'Record Voice';
            app.StartRecordingButton = uibutton(app.RecordVoiceTab, 'state');
            app.StartRecordingButton.ValueChangedFcn = createCallbackFcn(app, @StartRecordingButtonValueChanged, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.Label = uilabel(app.RecordVoiceTab);
            app.Label.Text = 'recording when red';
            app.lamp = uilamp(app.RecordVoiceTab);
            app.EntertexthereEditFieldLabel = uilabel(app.RecordVoiceTab);
            app.EntertexthereEditFieldLabel.Text = 'Enter text here';
            app.Entertext = uieditfield(app.RecordVoiceTab, 'text');
            app.Label_2 = uilabel(app.RecordVoiceTab);
            app.Label_2.Text = {'Choose a sentence with at least 20 words.'; 'Speak clearly, with brief pauses between words.'};
            app.PlotedRecordingTab = uitab(app.TabGroup);
            app.PlotedRecordingTab.Title = 'Ploted Recording';
            app.UIAxes = uiaxes(app.PlotedRecordingTab);
            title(app.UIAxes, 'Sound Waveform')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotIsolatedSignalTab = uitab(app.TabGroup);
            app.PlotIsolatedSignalTab.Title = 'Plot Isolated Signal';
            app.UIAxes2 = uiaxes(app.PlotIsolatedSignalTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ISOLATERECORDINGButton = uibutton(app.PlotIsolatedSignalTab, 'push');
            app.ISOLATERECORDINGButton.ButtonPushedFcn = createCallbackFcn(app, @ISOLATERECORDINGButtonPushed, true);
            app.ISOLATERECORDINGButton.Text = 'ISOLATE RECORDING';
            app.speakers1 = uibuttongroup(app.PlotIsolatedSignalTab);
            app.speakers1.Title = 'Which speaker?';
            app.Speaker1Button_4 = uiradiobutton(app.speakers1);
            app.Speaker1Button_4.Text = 'Speaker 1';
            app.Speaker1Button_4.Value = true;
            app.Speaker2Button_4 = uiradiobutton(app.speakers1);
            app.Speaker2Button_4.Text = 'Speaker 2';
            app.Speaker3Button_4 = uiradiobutton(app.speakers1);
            app.Speaker3Button_4.Text = 'Speaker 3';
            app.PlaybackTab = uitab(app.TabGroup);
            app.PlaybackTab.Title = 'Playback';
            app.UIAxes3 = uiaxes(app.PlaybackTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.speakers = uibuttongroup(app.PlaybackTab);
            app.speakers.SelectionChangedFcn = createCallbackFcn(app, @speakersSelectionChanged, true);
            app.speakers.Title = 'Which speaker?';
            app.Speaker1Button_3 = uiradiobutton(app.speakers);
            app.Speaker1Button_3.Text = 'Speaker 1';
            app.Speaker1Button_3.Value = true;
            app.Speaker2Button_3 = uiradiobutton(app.speakers);
            app.Speaker2Button_3.Text = 'Speaker 2';
            app.Speaker3Button_3 = uiradiobutton(app.speakers);
            app.Speaker3Button_3.Text = 'Speaker 3';
            app.WhatwouldyouliketoplaybackfromthelistwordsentenceEditFieldLabel = uilabel(app.PlaybackTab);
            app.WhatwouldyouliketoplaybackfromthelistwordsentenceEditFieldLabel.Text = 'What would you like to playback from the list (word/sentence)';
            app.ChooseWordEdit = uieditfield(app.PlaybackTab, 'text');
            app.WordstochoosefromListBox_3Label = uilabel(app.PlaybackTab);
            app.WordstochoosefromListBox_3Label.Text = 'Words to choose from:';
            app.listbox = uilistbox(app.PlaybackTab);
            app.listbox.Items = {};
            app.listbox.ValueChangedFcn = createCallbackFcn(app, @listboxValueChanged, true);
            app.listbox.Value = {};
            app.PlayButton = uibutton(app.PlaybackTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ClearButton = uibutton(app.PlaybackTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed2, true);
            app.ClearButton.Text = 'Clear';
            app.ConversationTab = uitab(app.TabGroup);
            app.ConversationTab.Title = 'Conversation';
            app.ConversationTab.ButtonDownFcn = createCallbackFcn(app, @ConversationTabButtonDown, true);
            app.speakers3 = uibuttongroup(app.ConversationTab);
            app.speakers3.SelectionChangedFcn = createCallbackFcn(app, @speakers3SelectionChanged, true);
            app.speakers3.Title = 'Which speaker?';
            app.Speaker1Button_5 = uiradiobutton(app.speakers3);
            app.Speaker1Button_5.Text = 'Speaker 1';
            app.Speaker1Button_5.Value = true;
            app.Speaker2Button_5 = uiradiobutton(app.speakers3);
            app.Speaker2Button_5.Text = 'Speaker 2';
            app.Speaker3Button_5 = uiradiobutton(app.speakers3);
            app.Speaker3Button_5.Text = 'Speaker 3';
            app.WordstochoosefromListBox_2Label = uilabel(app.ConversationTab);
            app.WordstochoosefromListBox_2Label.Text = 'Words to choose from:';
            app.listbox2 = uilistbox(app.ConversationTab);
            app.listbox2.Items = {};
            app.listbox2.ValueChangedFcn = createCallbackFcn(app, @listbox2ValueChanged, true);
            app.listbox2.Value = {};
            app.Clear = uibutton(app.ConversationTab, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'Clear';
            app.ADD = uibutton(app.ConversationTab, 'push');
            app.ADD.ButtonPushedFcn = createCallbackFcn(app, @ADDPushed, true);
            app.ADD.Text = 'ADD';
            app.Play2 = uibutton(app.ConversationTab, 'push');
            app.Play2.ButtonPushedFcn = createCallbackFcn(app, @Play2Pushed, true);
            app.Play2.Text = 'Play';
            app.Inputdialogue1sentenceatatimeEditFieldLabel = uilabel(app.ConversationTab);
            app.Inputdialogue1sentenceatatimeEditFieldLabel.Text = 'Input dialogue (1 sentence at a time)';
            app.InputDialogue = uieditfield(app.ConversationTab, 'text');
            app.FullDialogueTextAreaLabel = uilabel(app.ConversationTab);
            app.FullDialogueTextAreaLabel.Text = 'Full Dialogue';
            app.FullDialogue = uitextarea(app.ConversationTab);
            app.ExitProgramTab = uitab(app.TabGroup);
            app.ExitProgramTab.Title = 'Exit Program';
            app.ExitButton = uibutton(app.ExitProgramTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prac1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end